classdef Thermal_labeler < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        txt_subject             matlab.ui.control.Label
        txt_line_number         matlab.ui.control.Label
        txt_buffer_size         matlab.ui.control.Label
        btn_roi_add             matlab.ui.control.Button
        btn_roi_del             matlab.ui.control.Button
        edt_path                matlab.ui.control.EditField
        FilePathEditFieldLabel  matlab.ui.control.Label
        btn_save                matlab.ui.control.Button
        btn_next                matlab.ui.control.Button
        btn_prev                matlab.ui.control.Button
        btn_open                matlab.ui.control.Button
        ButtonGroup             matlab.ui.container.ButtonGroup
        radio_sleeping          matlab.ui.control.RadioButton
        radio_moving            matlab.ui.control.RadioButton
        radio_climbing          matlab.ui.control.RadioButton
        radio_rearing           matlab.ui.control.RadioButton
        radio_none              matlab.ui.control.RadioButton
        PercentageLabel         matlab.ui.control.Label
        gauge_perc              matlab.ui.control.SemicircularGauge
        ax_thresh               matlab.ui.control.UIAxes
        ax_main                 matlab.ui.control.UIAxes
        ax_frame                matlab.ui.control.UIAxes
    end

    % TODO non svuota il buffer
    % fas olo editing per ora
    % TODO retrieve behaviour
    
    properties (Access = private)
        path_folder % Description
        path_img % Description
        subject_name % Description
        file_mng % Description
        current_image
    end
    
    methods (Access = private)
        
        function plotter(app)

            imagesc(app.ax_frame, app.current_image.img);
            imagesc(app.ax_thresh, app.current_image.label);
            imagesc(app.ax_main, app.current_image.fusion);
            title(app.ax_main,['Frame ID:', num2str(app.current_image.id)]);

            app.txt_buffer_size.Text = ['Buffer Size: ', num2str(app.file_mng.img_buffer_n_elem)];
            app.txt_line_number.Text = ['Line ', num2str( app.file_mng.img_current_index-1 ), ' of ', num2str(app.file_mng.img_total) ];
            drawnow;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            disp('Loading...')
            

            app.path_folder = uigetdir('C:\Users\pupil\Downloads\test_24h\20230511_170231-ROI_1-tizio','Select subject:');
            app.path_img = fullfile(app.path_folder,'img.csv');
            if ~(exist(app.path_img,'file')==2)
                error('img file does not exist')
            end
            app.file_mng = RecManager(app.path_folder);

            app.gauge_perc.Value = app.file_mng.progress * 100;
            [a,app.subject_name] = fileparts(app.path_img);
            
            [app.current_image.img , app.current_image.label, app.current_image.id,app.current_image.is_editable] = app.file_mng.getNext;
            app.current_image.fusion = imfuse(app.current_image.img, app.current_image.label);

            for i = 1:length(app.ButtonGroup.Buttons)
                if i==1
                    app.current_image.behavior(i) = 1;
                else
                    app.current_image.behavior(i) = 0;
                end
            end
            
            plotter(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~isempty(app.file_mng)
                disp('Closing img file..')
                app.file_mng.close;
            end

            delete(app)
            
        end

        % Button pushed function: btn_next
        function btn_nextButtonPushed(app, event)
            
            if app.file_mng.hasNext
                [app.current_image.img , app.current_image.label, app.current_image.id,app.current_image.is_editable] = app.file_mng.getNext;
                app.current_image.fusion = imfuse(app.current_image.img, app.current_image.label);
                plotter(app);
            end
            
        end

        % Button pushed function: btn_prev
        function btn_prevButtonPushed(app, event)

            if app.file_mng.hasPrevious
                [app.current_image.img , app.current_image.label, app.current_image.id,app.current_image.is_editable] = app.file_mng.getPrevious;
                app.current_image.fusion = imfuse(app.current_image.img, app.current_image.label);
                plotter(app);
            end

        end

        % Button pushed function: btn_open
        function btn_openButtonPushed(app, event)
            
        end

        % Button pushed function: btn_save
        function btn_saveButtonPushed(app, event)
            for b = 1: length(app.data_buffer)
                app.file_label.addLine(app.data_buffer(b).label,app.data_buffer(b).id)
            end
            app.data_buffer = [];
            app.buffer_current_image_index = 0;
            app.btn_nextButtonPushed([]);
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            app.data_buffer(app.buffer_current_image_index).behavior = [app.ButtonGroup.Buttons(:).Value];

            disp(selectedButton)
        end

        % Button pushed function: btn_roi_del
        function btn_roi_delButtonPushed(app, event)
            roi = drawfreehand(app.ax_main);
            wait(roi);
            if roi.Position
                bw = createMask(roi);
                delete(roi)
                app.current_image.label = (app.current_image.label - bw) > 0;
                app.file_mng.editLabel(app.current_image.label,app.current_image.id);
                app.current_image.fusion = imfuse(app.current_image.img, app.current_image.label);
            else
                delete(roi)
            end
            plotter(app);
        end

        % Button pushed function: btn_roi_add
        function btn_roi_addButtonPushed(app, event)
            roi = drawfreehand(app.ax_main); 
            wait(roi);
            if roi.Position
                bw = createMask(roi);
                delete(roi)
                app.current_image.label = bw | app.current_image.label;
                app.file_mng.editLabel(app.current_image.label,app.current_image.id);
                app.current_image.fusion = imfuse(app.current_image.img, app.current_image.label);
            else
                delete(roi)
            end
            plotter(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ax_frame = uiaxes(app.UIFigure);
            title(app.ax_frame, 'Title')
            zlabel(app.ax_frame, 'Z')
            app.ax_frame.XTick = [];
            app.ax_frame.YTick = [];
            app.ax_main = uiaxes(app.UIFigure);
            title(app.ax_main, 'Title')
            zlabel(app.ax_main, 'Z')
            app.ax_main.XTick = [];
            app.ax_main.YTick = [];
            app.ax_thresh = uiaxes(app.UIFigure);
            title(app.ax_thresh, 'Title')
            app.ax_thresh.XTick = [];
            app.ax_thresh.YTick = [];
            app.gauge_perc = uigauge(app.UIFigure, 'semicircular');
            app.PercentageLabel = uilabel(app.UIFigure);
            app.PercentageLabel.Text = 'Percentage';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = 'Button Group';
            app.radio_none = uiradiobutton(app.ButtonGroup);
            app.radio_none.Text = 'NONE';
            app.radio_none.Value = true;
            app.radio_rearing = uiradiobutton(app.ButtonGroup);
            app.radio_rearing.Text = 'REARING';
            app.radio_climbing = uiradiobutton(app.ButtonGroup);
            app.radio_climbing.Text = 'CLIMBING';
            app.radio_moving = uiradiobutton(app.ButtonGroup);
            app.radio_moving.Text = 'MOVING';
            app.radio_sleeping = uiradiobutton(app.ButtonGroup);
            app.radio_sleeping.Text = 'SLEEPING';
            app.btn_open = uibutton(app.UIFigure, 'push');
            app.btn_open.ButtonPushedFcn = createCallbackFcn(app, @btn_openButtonPushed, true);
            app.btn_open.Text = 'OPEN';
            app.btn_prev = uibutton(app.UIFigure, 'push');
            app.btn_prev.ButtonPushedFcn = createCallbackFcn(app, @btn_prevButtonPushed, true);
            app.btn_prev.Text = 'PREV';
            app.btn_next = uibutton(app.UIFigure, 'push');
            app.btn_next.ButtonPushedFcn = createCallbackFcn(app, @btn_nextButtonPushed, true);
            app.btn_next.Text = 'NEXT';
            app.btn_save = uibutton(app.UIFigure, 'push');
            app.btn_save.ButtonPushedFcn = createCallbackFcn(app, @btn_saveButtonPushed, true);
            app.btn_save.Text = 'SAVE';
            app.FilePathEditFieldLabel = uilabel(app.UIFigure);
            app.FilePathEditFieldLabel.Text = 'File Path';
            app.edt_path = uieditfield(app.UIFigure, 'text');
            app.btn_roi_del = uibutton(app.UIFigure, 'push');
            app.btn_roi_del.ButtonPushedFcn = createCallbackFcn(app, @btn_roi_delButtonPushed, true);
            app.btn_roi_del.Text = '-';
            app.btn_roi_add = uibutton(app.UIFigure, 'push');
            app.btn_roi_add.ButtonPushedFcn = createCallbackFcn(app, @btn_roi_addButtonPushed, true);
            app.btn_roi_add.Text = '+';
            app.txt_buffer_size = uilabel(app.UIFigure);
            app.txt_buffer_size.Text = 'Buffer Size: 0';
            app.txt_line_number = uilabel(app.UIFigure);
            app.txt_line_number.Text = 'Line 0 of 0';
            app.txt_subject = uilabel(app.UIFigure);
            app.txt_subject.Text = 'Subject: None';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Thermal_labeler

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end